classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        EditFieldLabel        matlab.ui.control.Label
        EditField             matlab.ui.control.NumericEditField
        Button                matlab.ui.control.Button
        Button_2              matlab.ui.control.Button
        Button_3              matlab.ui.control.Button
        Button_4              matlab.ui.control.Button
        calculateButton       matlab.ui.control.Button
        resultEditFieldLabel  matlab.ui.control.Label
        resultEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        total = 0
        moove = "0"
    end
    

    methods (Access = private)

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            if app.moove == "0"
                app.total = value;
                app.resultEditField.Value = value;
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.moove = "+";
            app.EditField.Value = 0;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.moove = "-";
            app.EditField.Value = 0;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.moove = "*";
            app.EditField.Value = 0;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.moove = "/";
            app.EditField.Value = 0;
        end

        % Button pushed function: calculateButton
        function calculateButtonPushed(app, event)
            if app.moove == "0"
                app.EditField.Value = 0;
                app.resultEditField.Value = 0;
            else
                value1 = app.EditField.Value;
                value2 = app.resultEditField.Value;
                app.EditField.Value = 0;
                moove = app.moove;
                app.moove = "0";
                app.EditField.Value = 0; 
                if moove == "+"
                    app.resultEditField.Value = value2+value1;
                end
                if moove == "-"
                    app.resultEditField.Value = value2-value1;
                end
                if moove == "*"
                    app.resultEditField.Value = value2*value1;
                end
                if moove == "/"
                    app.resultEditField.Value = value2/value1;
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '*';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = {'/'; ''};
            app.calculateButton = uibutton(app.UIFigure, 'push');
            app.calculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculateButton.Text = 'calculate';
            app.resultEditFieldLabel = uilabel(app.UIFigure);
            app.resultEditFieldLabel.Text = 'result';
            app.resultEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end